classdef AttendanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainPanel                  matlab.ui.container.Panel
        LogoutButton               matlab.ui.control.Button
        CreateSlotButton           matlab.ui.control.Button
        SelectSlotButton           matlab.ui.control.Button
        SelectSlot                 matlab.ui.control.DropDown
        SlotDropdownDropDownLabel  matlab.ui.control.Label
        AddStudentButton           matlab.ui.control.Button
        StopAttendanceButton       matlab.ui.control.Button
        AttendancePanel            matlab.ui.container.Panel
        CameraAxes                 matlab.ui.control.UIAxes
        TakeAttendanceButton       matlab.ui.control.Button
        LoginPanel                 matlab.ui.container.Panel
        ShowPasswordCheckBox       matlab.ui.control.CheckBox
        NewuserSignupButton        matlab.ui.control.Button
        LoginButton                matlab.ui.control.Button
        Password                   matlab.ui.control.EditField
        Username                   matlab.ui.control.EditField
        PasswordLabel              matlab.ui.control.Label
        LoginLabel                 matlab.ui.control.Label
    end

  properties (Access = public)
        LoggedInUser              % Username after login
        SelectedSlot              % Selected slot name after login
        attendanceDataPath = 'attendance_data'  % Main folder path
        Property14 % Description
    end

    % ===== PRIVATE PROPERTIES =====
    properties (Access = private)
        cam                     % webcam object
        detector                % face detector
        isAttendanceRunning = false
        MarkedStudents = {}     % cell array of registration numbers already recorded this session
        lastUnknownAlertTime    % datetime of last unknown-face alert (to throttle popups)
        realPassword = ''       % Store actual password
        lastDetections          % Map for multi-frame validation
        debugMode = true;       % Set to true for debugging FaceNet
        facenetModel            % FaceNet model
        facenetInputSize = [160, 160]  % Standard FaceNet input size
    end
    

    methods (Access = private)
        % Get base path of your app
       function path = getBaseFolder(~)
    if isdeployed
        % Standalone execution - use the deployment root
        path = ctfroot;
    else
        % MATLAB execution - use the current file location
        path = fileparts(mfilename('fullpath'));
    end
end
        
        % Return user's folder and slot file
        function [userFolder, slotFile] = getUserSlotPaths(app)
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            userFolder = fullfile(baseFolder, app.attendanceDataPath, username);
            slotFile = fullfile(userFolder, 'slots.xlsx');
        end
        
        % Return absolute path for any file in attendance_data
        function fullPath = getDataFilePath(app, relativePath)
            baseFolder = app.getBaseFolder();
            fullPath = fullfile(baseFolder, app.attendanceDataPath, relativePath);
        end
        
        % Return path to selected slot folder
        function slotPath = getSlotPath(app)
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            slotName = app.SelectedSlot;
            slotPath = fullfile(baseFolder, app.attendanceDataPath, username, slotName);
        end
        
        % Return path to 'students' folder inside selected slot
        function studentFolder = getStudentBasePath(app)
            slotPath = app.getSlotPath();
            studentFolder = fullfile(slotPath, 'students');
            if ~exist(studentFolder, 'dir')
                mkdir(studentFolder);
            end
        end
        
        % Return full path to a specific student's folder
        function studentPath = getStudentFolder(app, studentName)
            base = app.getStudentBasePath();
            studentPath = fullfile(base, studentName);
        end
        
        % Ensure valid camera axes
        function ax = getSafeCameraAxes(app)
            ax = app.CameraAxes;
            if isempty(ax) || ~isvalid(ax)
                ax = uiaxes(app.AttendancePanel);
                ax.Position = [20 50 400 300];
                title(ax, ''); 
                xlabel(ax, ''); 
                ylabel(ax, '');
                app.CameraAxes = ax;
            end
            ax.Visible = 'on';
        end
        
        % Initialize webcam with better error handling
        function ok = initCamera(app)
            ok = false;
            try
                % Try to release any previous camera first
                try
                    app.CameraAxes.Visible = 'off';
                    title(app.CameraAxes, '');
                catch
                end

                % Create a fresh webcam object
                app.cam = webcam;
                
                % Test if camera is actually working
                try
                    testFrame = snapshot(app.cam);
                    if isempty(testFrame) || all(testFrame(:) == 0)
                        error('Camera returned blank frame');
                    end
                    ok = true;
                catch
                    error('Camera test frame failed');
                end
            catch ME
                errMsg = sprintf('Unable to access webcam:\n%s', ME.message);
                if isdeployed
                    errordlg(errMsg, 'Camera Error');
                else
                    uialert(app.UIFigure, errMsg, 'Camera Error', 'Icon', 'error');
                end
                ok = false;
            end
        end

        % Release camera
        function releaseCamera(app)
            try
                if ~isempty(app.cam)
                    try
                        delete(app.cam);
                    catch
                        try
                            clear app.cam;
                        catch
                        end
                    end
                    app.cam = [];
                end
            catch
            end

            % Remove any old image from axes
            try
                if isvalid(app.CameraAxes)
                    cla(app.CameraAxes);
                end
            catch
            end
        end
        
        % Log message for debugging
        function logMessage(~, message)
    logFile = fullfile(ctfroot, 'app.log');
    fid = fopen(logFile, 'a');
    fprintf(fid, '%s: %s\n', datestr(now), message);
    fclose(fid);
end
        
        % Load FaceNet model with enhanced error handling
        function loadFaceNetModel(app)
    try
        if isdeployed
            basePath = ctfroot;
            modelPath = fullfile(basePath, 'faceNet_model.mat');
            facenetPkgPath = fullfile(basePath, '+facenet_new');
        else
            modelPath = 'C:\Users\ztcha\Desktop\attendence app\faceNet_model.mat';
            facenetPkgPath = fullfile(fileparts(mfilename('fullpath')), '+facenet_new');
        end

        % Add FaceNet package to path
        if isfolder(facenetPkgPath)
            addpath(genpath(facenetPkgPath));
        else
            error('+facenet_new package not found at: %s', facenetPkgPath);
        end

        % Load the model
        if ~isfile(modelPath)
            error('Model file not found: %s', modelPath);
        end
        loadedData = load(modelPath);
        app.facenetModel = loadedData.net;

        % Test model
        testInput = zeros(app.facenetInputSize);
        testOutput = app.extractFaceNetEmbedding(testInput);
        app.logMessage(sprintf('Test output size: %s', mat2str(size(testOutput))));
    catch ME
        errorMsg = sprintf('Failed to load FaceNet: %s', ME.message);
        app.logMessage(errorMsg);
        uialert(app.UIFigure, errorMsg, 'Model Error', 'Icon', 'error');
        app.facenetModel = [];
    end
end
        
        % Manual test function for FaceNet model
        function testFaceNetModel(app)
            try
                if isempty(app.facenetModel)
                    app.logMessage('FaceNet model is empty. Attempting to load...');
                    app.loadFaceNetModel();
                end
                
                if ~isempty(app.facenetModel)
                    % Create a test image
                    testImg = zeros(160, 160, 3);
                    
                    % Try to get an embedding
                    embedding = app.extractFaceNetEmbedding(testImg);
                    app.logMessage(sprintf('FaceNet test successful. Embedding size: %s', mat2str(size(embedding))));
                    
                    msgbox('FaceNet model is working correctly!', 'Test Successful', 'help');
                else
                    app.logMessage('FaceNet model is still empty after loading attempt.');
                    errordlg('FaceNet model could not be loaded.', 'Model Error');
                end
            catch ME
                app.logMessage(sprintf('FaceNet test failed: %s', ME.message));
                errordlg(sprintf('FaceNet test failed: %s', ME.message), 'Test Failed');
            end
        end
        
        % Preprocess face image for FaceNet
        function processedFace = preprocessFaceForFaceNet(app, faceImage)
            % Convert to RGB if grayscale
            if size(faceImage, 3) == 1
                faceImage = repmat(faceImage, [1, 1, 3]);
            end
            
            % Resize to FaceNet input size
            processedFace = imresize(faceImage, app.facenetInputSize);
            
            % Normalize pixel values to [0, 1]
            processedFace = im2double(processedFace);
            
            % Normalize using ImageNet standards (common for FaceNet)
            meanImg = [0.485, 0.456, 0.406];
            stdImg = [0.229, 0.224, 0.225];
            for i = 1:3
                processedFace(:,:,i) = (processedFace(:,:,i) - meanImg(i)) / stdImg(i);
            end
        end
        
        % Extract FaceNet embeddings with proper model handling
        function embedding = extractFaceNetEmbedding(app, faceImage)
            if isempty(app.facenetModel)
                error('FaceNet model not loaded');
            end
            
            % Preprocess the face
            processedFace = app.preprocessFaceForFaceNet(faceImage);
            
            % Handle different model formats
            if isa(app.facenetModel, 'DAGNetwork') || isa(app.facenetModel, 'SeriesNetwork')
                % For standard networks - use standard predict
                embedding = predict(app.facenetModel, processedFace);
                
            elseif isa(app.facenetModel, 'dlnetwork')
                % For dlnetwork
                processedFaceDL = dlarray(processedFace, 'SSCB');
                embedding = predict(app.facenetModel, processedFaceDL);
                embedding = extractdata(embedding);
                
            elseif isa(app.facenetModel, 'LayerGraph')
                % For LayerGraph - convert to DAGNetwork first
                net = dagNetwork(app.facenetModel);
                embedding = predict(net, processedFace);
                
            else
                % Try generic predict with proper input formatting
                try
                    % Standard format for most networks
                    embedding = predict(app.facenetModel, processedFace);
                catch
                    % Alternative format if standard fails
                    processedFace = permute(processedFace, [3, 2, 1]); % Change to CxWxH
                    processedFace = reshape(processedFace, [1, size(processedFace)]); % Add batch dimension
                    embedding = predict(app.facenetModel, processedFace);
                end
            end
            
            % Ensure embedding is a vector (flatten if needed)
            embedding = embedding(:);
            
            % Normalize embedding to unit length
            if any(embedding) && norm(embedding) > 0 % Only normalize if not all zeros
                embedding = embedding / norm(embedding);
            else
                embedding = zeros(size(embedding));
            end
            
            % Debug output
            if app.debugMode
                app.logMessage(sprintf('Embedding size: %s, Norm: %.4f', mat2str(size(embedding)), norm(embedding)));
            end
        end
        
        % Calculate cosine similarity between two embeddings
        function similarity = cosineSimilarity(~, embedding1, embedding2)
            if isempty(embedding1) || isempty(embedding2) || all(embedding1 == 0) || all(embedding2 == 0)
                similarity = -Inf;
                return;
            end
            
            % Ensure both are column vectors
            embedding1 = embedding1(:);
            embedding2 = embedding2(:);
            
            % Calculate cosine similarity
            similarity = dot(embedding1, embedding2) / (norm(embedding1) * norm(embedding2));
            
            % Handle NaN results
            if isnan(similarity)
                similarity = -Inf;
            end
        end
        
        % Find best match with FaceNet embeddings
        function [bestStudent, bestScore, regNo] = findBestMatch(app, queryEmbedding, studentsTbl, studentBase)
            bestStudent = "";
            bestScore = -Inf;
            regNo = "NA";
            
            % If no students exist, return early
            if ~exist(studentBase, 'dir')
                return;
            end
            
            dirList = dir(studentBase);
            if isempty(dirList) || length(dirList) <= 2
                return;
            end
            
            for s = 1:length(dirList)
                studentName = dirList(s).name;
                if ~dirList(s).isdir || startsWith(studentName, '.'), continue; end
                
                studentFolder = fullfile(studentBase, studentName);
                % Look for FaceNet embedding files
                featureFiles = dir(fullfile(studentFolder, 'faceNetEmbedding_*.mat'));
                if isempty(featureFiles), continue; end

                maxSim = -Inf;
                for k = 1:length(featureFiles)
                    try
                        % Load the stored embedding
                        loadedData = load(fullfile(featureFiles(k).folder, featureFiles(k).name));
                        storedEmbedding = loadedData.embedding;
                        
                        % Calculate cosine similarity
                        sim = app.cosineSimilarity(queryEmbedding, storedEmbedding);
                        
                        if sim > maxSim, maxSim = sim; end
                    catch ME
                        if app.debugMode
                            app.logMessage(sprintf('Error loading embedding for %s: %s', studentName, ME.message));
                        end
                        continue;
                    end
                end
                
                % Enhanced debug information
                if app.debugMode
                    app.logMessage(sprintf('Student: %s, FaceNet Score: %.4f, Best Score: %.4f', studentName, maxSim, bestScore));
                end
                
                if maxSim > bestScore
                    bestScore = maxSim;
                    bestStudent = studentName;
                    
                    % Find registration number
                    if ~isempty(studentsTbl) && any(strcmp(studentsTbl.Properties.VariableNames, 'Name'))
                        idx = find(studentsTbl.Name == bestStudent, 1);
                        if ~isempty(idx) && any(strcmp(studentsTbl.Properties.VariableNames, 'RegistrationNumber'))
                            regNo = string(studentsTbl.RegistrationNumber(idx));
                        end
                    end
                end
            end
        end
        
        % Confirm Slot Selection
        function ConfirmSlotSelection(app, ~)
            selected = app.SelectSlot.Value;
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a slot from the dropdown.', 'No Selection', 'Icon', 'warning');
                return;
            end

            % Save selected slot
            app.SelectedSlot = selected;

            % Show main panel for attendance
            app.MainPanel.Visible = 'on';
            app.AttendancePanel.Visible = 'on';

            % Alert user
            uialert(app.UIFigure, ['Slot "' char(selected) '" selected successfully.'], ...
                'Slot Selected', 'Icon', 'success');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      % Only Login visible at start
            app.LoginPanel.Visible = 'on';
            app.MainPanel.Visible = 'off';
            app.AttendancePanel.Visible = 'off';
            app.loadFaceNetModel();
            % Force include facenet_new package
            if isdeployed
            dummy = facenet_new.someFunction(); % Replace with actual function
            end
            % Hide dropdown components initially
            app.SelectSlot.Visible = 'off';
            app.SlotDropdownDropDownLabel.Visible = 'off';
            
            app.lastUnknownAlertTime = datetime('2000-01-01');
            app.lastDetections = containers.Map;
            
            % Create a debug log file
            if isdeployed
                debugFile = fullfile(app.getBaseFolder(), 'app_debug.log');
                fid = fopen(debugFile, 'w');
                if fid ~= -1
                    fprintf(fid, 'Application started at %s\n', datestr(now));
                    fclose(fid);
                end
            end
            
            % Initialize face detector with better error handling
            try
                app.detector = vision.CascadeObjectDetector(...
                    'ClassificationModel', 'FrontalFaceLBP',...
                    'MinSize', [50 50],...
                    'MaxSize', [300 300],...
                    'MergeThreshold', 4);
                
                if app.debugMode
                    app.logMessage('Face detector initialized successfully.');
                end
            catch ME
                errMsg = sprintf('Failed to initialize face detector:\n%s', ME.message);
                if isdeployed
                    errordlg(errMsg, 'Detector Error');
                else
                    uialert(app.UIFigure, errMsg, 'Detector Error', 'Icon', 'error');
                end
            end
            
            % Load FaceNet model
            app.loadFaceNetModel();
            
            % Close any stray figures/uifigures
            try
                figs = findall(0, 'Type', 'figure');
                for i = 1:numel(figs)
                    if figs(i) ~= app.UIFigure
                        try, close(figs(i)); catch, end
                    end
                end
                uifigs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(uifigs)
                    if uifigs(i) ~= app.UIFigure
                        try, close(uifigs(i)); catch, end
                    end
                end
            catch
            end
            
            % Center window
            try, movegui(app.UIFigure, 'center'); catch, end
            
            % Set initial password masking state
            app.realPassword = '';
            app.ShowPasswordCheckBox.Value = false;
            
            % Attach the real-time masking function to the password field
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            
            % Apply colorful UI scheme with grey backgrounds
            app.UIFigure.Color = [0.5 0.5 0.5]; % Medium grey background
            app.LoginPanel.Title = 'Login';
            app.LoginPanel.BackgroundColor = [0.5 0.5 0.5]; % Medium grey
            app.LoginPanel.FontSize = 16;
            app.LoginLabel.FontWeight = 'bold';
            app.LoginLabel.FontColor = [0.2 0.4 0.8]; % Vibrant blue
            app.PasswordLabel.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.Username.BackgroundColor = [1 1 1]; % White
            app.Username.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.Password.BackgroundColor = [1 1 1]; % White
            app.Password.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.LoginButton.BackgroundColor = [0.2 0.4 0.8]; % Vibrant blue
            app.LoginButton.FontColor = [1 1 1]; % White text
            app.NewuserSignupButton.BackgroundColor = [0.2 0.4 0.8]; % Vibrant blue
            app.NewuserSignupButton.FontColor = [1 1 1]; % White text
            app.NewuserSignupButton.Text = 'New User? Sign Up';
            app.ShowPasswordCheckBox.Text = 'Show Password';
            app.ShowPasswordCheckBox.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.MainPanel.Title = 'Main Menu';
            app.MainPanel.BackgroundColor = [0.5 0.5 0.5]; % Medium grey
            app.TakeAttendanceButton.BackgroundColor = [0.9 0.5 0.2]; % Orange for primary action
            app.TakeAttendanceButton.FontColor = [1 1 1]; % White text
            app.AttendancePanel.BackgroundColor = [0.5 0.5 0.5]; % Medium grey
            title(app.CameraAxes, 'Camera Feed', 'Color', [0.2 0.4 0.8]); % Blue title
            xlabel(app.CameraAxes, '')
            ylabel(app.CameraAxes, '')
            zlabel(app.CameraAxes, '')
            app.CameraAxes.Box = 'on';
            app.CameraAxes.XColor = [0.2 0.4 0.8]; % Blue border
            app.CameraAxes.YColor = [0.2 0.4 0.8]; % Blue border
            app.StopAttendanceButton.BackgroundColor = [0.2 0.4 0.8]; % Blue
            app.StopAttendanceButton.FontColor = [1 1 1]; % White text
            app.AddStudentButton.BackgroundColor = [0.2 0.4 0.8]; % Blue
            app.AddStudentButton.FontColor = [1 1 1]; % White text
            app.SlotDropdownDropDownLabel.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.SlotDropdownDropDownLabel.Text = 'Select Slot';
            app.SelectSlot.BackgroundColor = [0.3 0.7 0.5]; % Green
            app.SelectSlot.FontColor = [0.2 0.2 0.2]; % Dark gray
            app.SelectSlotButton.BackgroundColor = [0.2 0.4 0.8]; % Blue
            app.SelectSlotButton.FontColor = [1 1 1]; % White text
            app.CreateSlotButton.BackgroundColor = [0.2 0.4 0.8]; % Blue
            app.CreateSlotButton.FontColor = [1 1 1]; % White text
            app.CreateSlotButton.Text = 'Create Slot';
            app.LogoutButton.BackgroundColor = [0.9 0.5 0.2]; % Orange for emphasis
            app.LogoutButton.FontColor = [1 1 1]; % White text
            
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
% Close stray figures/uifigures except app window
            try
                figs = findall(0, 'Type', 'figure');
                for i = 1:numel(figs)
                    if figs(i) ~= app.UIFigure
                        try, close(figs(i)); catch, end
                    end
                end
                uifigs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(uifigs)
                    if uifigs(i) ~= app.UIFigure
                        try, close(uifigs(i)); catch, end
                    end
                end
            catch
            end
            
            % Read inputs
            username = app.Username.Value;
            password = app.realPassword;
            
            % STRICT early return
            if isempty(username) || isempty(strtrim(char(string(username))))
                uialert(app.UIFigure, 'Please enter your username.', 'Login', 'Icon', 'warning');
                return;
            end
            if isempty(password) || isempty(strtrim(char(string(password))))
                uialert(app.UIFigure, 'Please enter your password.', 'Login', 'Icon', 'warning');
                return;
            end
            
            % Verify credentials - FIXED PATH
            loginFile = fullfile(app.getBaseFolder(), app.attendanceDataPath, 'login_data.xlsx');
            
            % Debug output
            if app.debugMode
                app.logMessage(sprintf('Looking for login file at: %s', loginFile));
            end
            
            if ~isfile(loginFile)
                uialert(app.UIFigure, 'Login data not found. Please sign up first.', 'Error', 'Icon', 'error');
                return;
            end
            
            try
                data = readcell(loginFile);
            catch
                uialert(app.UIFigure, 'Unable to read login data file.', 'File Error', 'Icon', 'error');
                return;
            end
            
            isValid = false;
            if size(data,1) >= 2
                for i = 2:size(data, 1)
                    savedUsername = strtrim(string(data{i,1}));
                    savedPassword = strtrim(string(data{i,2}));
                    if strcmp(savedUsername, string(username)) && strcmp(savedPassword, string(password))
                        isValid = true;
                        break;
                    end
                end
            end
            if ~isValid
                uialert(app.UIFigure, 'Invalid username or password.', 'Login Failed', 'Icon', 'error');
                return;
            end
            
            % SUCCESS: Disable login panel, show main
            app.LoggedInUser = username;
            app.LoginPanel.Enable = 'off';   % disable login panel inputs
            app.MainPanel.Visible = 'on';    
            app.AttendancePanel.Visible = 'off';
            
            % Prepare user folders - FIXED PATH
            userPath = fullfile(app.getBaseFolder(), app.attendanceDataPath, username);
            if ~isfolder(userPath)
                mkdir(userPath);
            end
            
            % Load slots into dropdown
            userSlotFile = fullfile(userPath, 'slots.xlsx');
            try
                if isfile(userSlotFile)
                    slots = readcell(userSlotFile);
                    if size(slots,1) > 1
                        slots = slots(2:end, 1);
                        slots = slots(~cellfun('isempty', slots));
                        app.SelectSlot.Items = string(slots);
                    else
                        app.SelectSlot.Items = {};
                    end
                else
                    app.SelectSlot.Items = {};
                end
            catch
                app.SelectSlot.Items = {};
            end
            
            % Success alert
            try
                uialert(app.UIFigure, ...
                    ['Welcome, ' char(username) '! Select or create a slot to continue.'], ...
                    'Login Successful', 'Icon', 'success');
            catch
            end
        end

        % Button pushed function: NewuserSignupButton
        function NewuserSignupButtonPushed(app, event)
  % Create input dialog for new user registration
            prompt = {'Enter Username:', 'Enter Password:'};
            dlgtitle = 'New User Sign Up';
            dims = [1 35];
            definput = {'', ''};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Check if user cancelled the dialog
            if isempty(answer)
                return;
            end
            
            % Get username and password from dialog
            username = strtrim(answer{1});
            password = strtrim(answer{2});
            
            % Validate inputs
            if isempty(username)
                uialert(app.UIFigure, 'Username cannot be empty.', 'Signup Error', 'Icon', 'warning');
                return;
            end
            if isempty(password)
                uialert(app.UIFigure, 'Password cannot be empty.', 'Signup Error', 'Icon', 'warning');
                return;
            end
            
            % Ensure login data directory exists - FIXED PATH
            loginFile = fullfile(app.getBaseFolder(), app.attendanceDataPath, 'login_data.xlsx');
            loginDataDir = fileparts(loginFile);
            if ~isfolder(loginDataDir)
                mkdir(loginDataDir);
            end
            
            % Check for existing username
            if isfile(loginFile)
                try
                    existingData = readcell(loginFile);
                    if size(existingData, 1) > 1
                        existingUsernames = existingData(2:end, 1);
                        if any(strcmpi(existingUsernames, username))
                            uialert(app.UIFigure, 'Username already exists.', 'Signup Error', 'Icon', 'warning');
                            return;
                        end
                    end
                catch
                    % File might be corrupt or empty, we'll recreate it
                    existingData = {'Username', 'Password'};
                end
            else
                existingData = {'Username', 'Password'};
            end
            
            % Append new user credentials
            newEntry = {username, password};
            writecell([existingData; newEntry], loginFile);
            
            % Create user directory (without any attendance files) - FIXED PATH
            userFolder = fullfile(app.getBaseFolder(), app.attendanceDataPath, username);
            if ~exist(userFolder, 'dir')
                mkdir(userFolder);
            end
            
            % Create slots file with header only
            slotFile = fullfile(userFolder, 'slots.xlsx');
            if ~isfile(slotFile)
                writecell({'SlotName'}, slotFile);
            end
            
            uialert(app.UIFigure, 'Signup successful! You can now log in.', 'Success', 'Icon', 'success');
        end

        % Button pushed function: CreateSlotButton
        function CreateSlotButtonPushed(app, event)
% Get slot name with proper cancel handling
            answer = inputdlg({'Enter new slot name:'}, 'Create Slot', [1 50], {''});
            
            % Check if user clicked Cancel or closed dialog
            if isempty(answer)
                return;
            end
            
            % Check if user entered empty string
            if isempty(answer{1}) || isempty(strtrim(answer{1}))
                uialert(app.UIFigure, 'Slot name cannot be empty.', 'Error', 'Icon', 'warning');
                return;
            end
            
            newSlot = string(strtrim(answer{1}));
            if newSlot == "" || strlength(newSlot) == 0
                uialert(app.UIFigure, 'Slot name cannot be empty.', 'Error', 'Icon', 'warning');
                return;
            end
            
            % Fixed invalid chars
            invalidChars = ['<' '>' ':' '"' '/' '\' '|' '?' '*'];
            if any(ismember(char(newSlot), invalidChars))
                uialert(app.UIFigure, 'Slot name contains invalid characters: \ / : * ? " < > |', 'Invalid Name', 'Icon', 'warning');
                return;
            end
            
            % Use absolute path - FIXED
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            userFolder = fullfile(baseFolder, app.attendanceDataPath, username);
            slotFile = fullfile(userFolder, 'slots.xlsx');
            slotFolder = fullfile(userFolder, char(newSlot));
            
            if ~exist(userFolder, 'dir')
                try
                    mkdir(userFolder);
                catch
                    uialert(app.UIFigure, 'Could not create user folder.', 'Folder Error', 'Icon', 'error');
                    return;
                end
            end
            
            if exist(slotFolder, 'dir')
                uialert(app.UIFigure, 'Slot already exists.', 'Duplicate Slot', 'Icon', 'warning');
                return;
            end
            
            try
                mkdir(slotFolder);
                mkdir(fullfile(slotFolder, 'students'));
            catch e
                uialert(app.UIFigure, ['Failed to create slot folder: ' e.message], 'Folder Error', 'Icon', 'error');
                return;
            end
            
            try
                slotData = {};
                if isfile(slotFile)
                    slotData = readcell(slotFile);
                end
                if isempty(slotData) || ~(iscell(slotData) && size(slotData,2) >= 1)
                    slotData = {'SlotName'};
                end
                existingSlots = string(slotData(2:end, 1));
                if ~any(existingSlots == newSlot)
                    slotData(end+1, :) = {char(newSlot)};
                    writecell(slotData, slotFile);
                end
            catch e
                uialert(app.UIFigure, ['Error updating slots.xlsx: ' e.message], 'File Error', 'Icon', 'error');
            end
            
            try
                if isfile(slotFile)
                    allData = readcell(slotFile);
                    if size(allData,1) > 1
                        rawSlots = allData(2:end, 1);
                        rawSlots = rawSlots(~cellfun('isempty', rawSlots));
                        app.SelectSlot.Items = string(rawSlots);
                    else
                        app.SelectSlot.Items = {};
                    end
                else
                    app.SelectSlot.Items = {};
                end
            catch
                app.SelectSlot.Items = {};
            end
            
            uialert(app.UIFigure, ['Slot "' char(newSlot) '" created.'], 'Success', 'Icon', 'success');
       
        end

        % Button pushed function: SelectSlotButton
        function SelectSlotButtonPushed(app, event)
       
            if isempty(app.LoggedInUser)
                uialert(app.UIFigure, 'Please log in first.', 'Error', 'Icon', 'warning');
                return;
            end

            if isempty(app.SelectSlot.Items)
                uialert(app.UIFigure, 'No slots available. Please create a slot first.', 'No Slots', 'Icon', 'warning');
                return;
            end

            % Make dropdown and label visible
            app.SelectSlot.Visible = 'on';
            app.SlotDropdownDropDownLabel.Visible = 'on';

            % Change button text and assign callback
            app.SelectSlotButton.Text = 'Confirm Selection';
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @app.ConfirmSlotSelection);
            
        end

        % Button pushed function: TakeAttendanceButton
        function TakeAttendanceButtonPushed(app, event)
  % --- PRE-CHECKS ---
            if isempty(app.SelectedSlot)
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return;
            end
            
            if isempty(app.cam)
                % Close stray figures
                try
                    figs = findall(0, 'Type', 'figure');
                    for i = 1:numel(figs)
                        if figs(i) ~= app.UIFigure, try close(figs(i)); catch, end; end
                    end
                    uifigs = findall(0, 'Type', 'uifigure');
                    for i = 1:numel(uifigs)
                        if uifigs(i) ~= app.UIFigure, try close(uifigs(i)); catch, end; end
                    end
                catch
                end
            end
            
            % Check if FaceNet model is loaded
            if isempty(app.facenetModel)
                uialert(app.UIFigure, 'FaceNet model not loaded. Please restart the application.', 'Model Error', 'Icon', 'error');
                return;
            end
            
            % --- SETUP ---
            app.AttendancePanel.Visible = 'on';
            % ensure axes are fresh
            try
                cla(app.CameraAxes);
            catch
            end

            app.CameraAxes.Visible = 'on';
            ax = app.getSafeCameraAxes();

            % Initialize webcam and detector
            try
                if ~app.initCamera()
                    return;
                end
            catch
                uialert(app.UIFigure, 'Unable to access webcam.', 'Camera Error', 'Icon', 'error');
                return;
            end
            
            app.isAttendanceRunning = true;
            username = app.LoggedInUser;
            slot = app.SelectedSlot;
            
            % Use absolute path - FIXED
            slotPath = fullfile(app.getBaseFolder(), app.attendanceDataPath, username, slot);
            studentBase = fullfile(slotPath, 'students');

            if ~isfolder(studentBase)
                uialert(app.UIFigure, 'Student data folder not found for this slot.', 'Folder Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end

            % --- ATTENDANCE FILE ---
            todayDate = datestr(now, 'yyyy-mm-dd');
            attendanceFile = fullfile(slotPath, ['attendance_' todayDate '.xlsx']);

            % Create attendance file with headers if missing
            if ~isfile(attendanceFile)
                headers = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                writecell(headers, attendanceFile);
            else
                try
                    checkTbl = readtable(attendanceFile, 'TextType', 'string');
                    expectedHeaders = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                    if ~all(ismember(expectedHeaders, checkTbl.Properties.VariableNames))
                        % Add missing headers
                        oldData = readcell(attendanceFile);
                        writecell([expectedHeaders; oldData(2:end,:)], attendanceFile);
                    end
                catch
                    % If file corrupt, recreate with headers
                    headers = {'Name','RegistrationNumber','Date','Latitude','Longitude'};
                    writecell(headers, attendanceFile);
                end
            end

            % Load already marked students to prevent duplicates
            app.MarkedStudents = {};
            try
                attendanceData = readtable(attendanceFile, 'TextType', 'string');
                if ~isempty(attendanceData) && any(strcmp(attendanceData.Properties.VariableNames, 'RegistrationNumber'))
                    existingRegNos = attendanceData.RegistrationNumber;
                    existingRegNos = existingRegNos(~ismissing(existingRegNos) & existingRegNos ~= "");
                    app.MarkedStudents = cellstr(unique(existingRegNos));
                end
            catch
            end

            % Load student database - FIXED PATH
            studentsFile = fullfile(slotPath, 'students.xlsx');
            if ~isfile(studentsFile)
                uialert(app.UIFigure, 'students.xlsx not found. Please add students to this slot.', 'File Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end
            try
                studentsTbl = readtable(studentsFile, 'TextType', 'string');
            catch
                uialert(app.UIFigure, 'Could not read students.xlsx. Check format.', 'File Error', 'Icon', 'error');
                app.isAttendanceRunning = false;
                return;
            end

            % --- ATTENDANCE LOOP ---
            popupCooldown = 5; % seconds
            lastPopupTimeMap = containers.Map; % key: regNo, value: datetime
            
            % Reset multi-frame validation
            app.lastDetections = containers.Map;
            
            % Display instructions
            title(ax, 'Position your face in the frame', 'Color', 'white');
            pause(2);

            while app.isAttendanceRunning
                frame = snapshot(app.cam);
                
                % Display the original frame
                imshow(frame, 'Parent', ax);
                drawnow limitrate;

                % Detect faces
                grayFrame = rgb2gray(frame);
                bboxes = step(app.detector, grayFrame);
                
                % Debug output
                if app.debugMode
                    app.logMessage(sprintf('Detected %d faces in frame', size(bboxes, 1)));
                end
                
                % If no faces detected, continue
                if isempty(bboxes)
                    title(ax, 'No face detected - position face in center', 'Color', 'red');
                    pause(0.1);
                    continue;
                end

                % Process each detected face
                for i = 1:size(bboxes, 1)
                    bbox = bboxes(i, :);
                    
                    % Draw bounding box on the original frame
                    rectangle(ax, 'Position', bbox, 'EdgeColor', 'green', 'LineWidth', 2);
                    
                    % Extract face from the original color frame
                    faceImg = imcrop(frame, bbox);
                    
                    try
                        % Extract FaceNet embedding
                        queryEmbedding = app.extractFaceNetEmbedding(faceImg);
                        
                        % Find best match
                        [bestStudent, bestScore, regNo] = app.findBestMatch(queryEmbedding, studentsTbl, studentBase);
                        
                        nowT = datetime('now');
                        
                        % Debug information
                        if app.debugMode
                            app.logMessage(sprintf('Best match: %s, FaceNet Score: %.4f', bestStudent, bestScore));
                        end
                        
                        % FaceNet typically uses a threshold around 0.6-0.7 for cosine similarity
                        if bestScore >= 0.65 && bestStudent ~= ""
                            % Skip if already marked
                            if any(strcmpi(app.MarkedStudents, char(regNo)))
                                if ~isKey(lastPopupTimeMap, char(regNo)) || seconds(nowT - lastPopupTimeMap(char(regNo))) > popupCooldown
                                    title(ax, sprintf('Already marked: %s', bestStudent), 'Color', 'yellow');
                                    autoCloseAlert(sprintf('Attendance already taken for: %s (%s)', bestStudent, regNo), 'Info', 'info', 2);
                                    lastPopupTimeMap(char(regNo)) = nowT;
                                end
                            else
                                % Multi-frame validation
                                if ~isKey(app.lastDetections, char(regNo))
                                    app.lastDetections(char(regNo)) = 1;
                                else
                                    app.lastDetections(char(regNo)) = app.lastDetections(char(regNo)) + 1;
                                end
                                
                                % Only mark attendance after 3 consistent detections
                                if app.lastDetections(char(regNo)) >= 3
                                    % Mark attendance
                                    app.MarkedStudents{end+1} = char(regNo);
                                    timeStr = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                                    lat = "Unknown"; lon = "Unknown";
                                    row = {char(bestStudent), char(regNo), timeStr, lat, lon};
                                    try
                                        writecell(row, attendanceFile, 'WriteMode', 'append');
                                    catch
                                        old = readcell(attendanceFile);
                                        new = [old; row];
                                        writecell(new, attendanceFile);
                                    end
                                    title(ax, sprintf('Marked: %s', bestStudent), 'Color', 'green');
                                    autoCloseAlert(sprintf('Attendance marked: %s (%s)', bestStudent, regNo), 'Success', 'success', 2);
                                    lastPopupTimeMap(char(regNo)) = nowT;
                                    app.lastDetections(char(regNo)) = 0; % Reset counter
                                else
                                    title(ax, sprintf('Verifying: %s (%d/3)', bestStudent, app.lastDetections(char(regNo))), 'Color', 'yellow');
                                end
                            end
                        else
                            % Provide more detailed feedback
                            if bestStudent == ""
                                title(ax, 'Unknown face - not in database', 'Color', 'red');
                            else
                                title(ax, sprintf('Low confidence: %.2f', bestScore), 'Color', 'red');
                            end
                        end
                    catch ME
                        title(ax, 'FaceNet processing error', 'Color', 'red');
                        if app.debugMode
                            app.logMessage(sprintf('FaceNet error: %s', ME.message));
                        end
                    end
                end
                pause(0.2);
            end

            % --- NESTED FUNCTIONS ---
            function autoCloseAlert(msg, title, icon, delay)
                uialert(app.UIFigure, msg, title, 'Icon', icon);
                t = timer('StartDelay', delay, 'TimerFcn', @(~,~) closeAlertByTitle(title));
                start(t);
            end

            function closeAlertByTitle(title)
                figs = findall(0, 'Type', 'uifigure');
                for i = 1:numel(figs)
                    if strcmp(figs(i).Name, title)
                        try close(figs(i)); catch, end
                    end
                end
            end
        end

        % Button pushed function: StopAttendanceButton
        function StopAttendanceButtonPushed(app, event)
   % Check if slot is selected first
            if isempty(app.SelectedSlot)
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return;
            end
            
            app.isAttendanceRunning = false;
            try
                if ~isempty(app.cam)
                    delete(app.cam);
                    app.cam = [];
                end
            catch
                try clear app.cam; app.cam = []; catch, end
            end
            app.AttendancePanel.Visible = 'off';
            try
                cla(app.CameraAxes);
            catch
            end
            uialert(app.UIFigure, 'Attendance session stopped and camera released.', 'Info', 'Icon', 'success');
        end

        % Button down function: MainPanel
        function MainPanelButtonDown(app, event)
            
        end

        % Button pushed function: AddStudentButton
        function AddStudentButtonPushed(app, event)
   % --- PRE-CHECKS ---
            if isempty(app.LoggedInUser)
                uialert(app.UIFigure, 'Please log in first.', 'Error', 'Icon', 'warning');
                return;
            end
            
            if isempty(app.SelectedSlot)
                uialert(app.UIFigure, 'Please select a slot first.', 'No Slot Selected', 'Icon', 'warning');
                return;
            end
            
            % --- GET STUDENT INFO ---
            % Create input dialog for student details
            prompt = {'Student Name:', 'Registration Number:'};
            dlgtitle = 'Add New Student';
            dims = [1 35];
            definput = {'', ''};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Check if user cancelled
            if isempty(answer)
                return;
            end
            
            % Validate inputs
            studentName = strtrim(answer{1});
            regNumber = strtrim(answer{2});
            
            if isempty(studentName) || isempty(regNumber)
                uialert(app.UIFigure, 'Both name and registration number are required.', 'Input Error', 'Icon', 'warning');
                return;
            end
            
            % Check for invalid characters in name (for folder naming)
            invalidChars = ['<' '>' ':' '"' '/' '\' '|' '?' '*'];
            if any(ismember(studentName, invalidChars))
                uialert(app.UIFigure, 'Name contains invalid characters: \ / : * ? " < > |', 'Invalid Name', 'Icon', 'warning');
                return;
            end
            
            % --- SETUP PATHS ---
            % Use absolute path - FIXED
            baseFolder = app.getBaseFolder();
            username = app.LoggedInUser;
            slotName = app.SelectedSlot;
            
            slotPath = fullfile(baseFolder, app.attendanceDataPath, username, slotName);
            studentsFile = fullfile(slotPath, 'students.xlsx');
            studentBase = fullfile(slotPath, 'students');
            studentFolder = fullfile(studentBase, studentName);
            
            % Debug output
            if app.debugMode
                app.logMessage(sprintf('Student folder path: %s', studentFolder));
                app.logMessage(sprintf('Students file path: %s', studentsFile));
            end
            
            % Check if student already exists
            if exist(studentFolder, 'dir')
                uialert(app.UIFigure, 'A student with this name already exists in this slot.', 'Duplicate Entry', 'Icon', 'warning');
                return;
            end
            
            % Check if registration number already exists in students.xlsx
            if isfile(studentsFile)
                try
                    existingStudents = readtable(studentsFile, 'TextType', 'string');
                    if any(existingStudents.RegistrationNumber == regNumber)
                        uialert(app.UIFigure, 'This registration number already exists in this slot.', 'Duplicate Entry', 'Icon', 'warning');
                        return;
                    end
                catch
                    % File might be corrupt, we'll recreate it later
                end
            end
            
            % --- INITIALIZE CAMERA ---
            if isempty(app.cam) && ~app.initCamera()
                uialert(app.UIFigure, 'Failed to initialize camera.', 'Camera Error', 'Icon', 'error');
                return;
            end
            
            % --- MULTI-ANGLE ENROLLMENT ---
            app.AttendancePanel.Visible = 'on';
            ax = app.getSafeCameraAxes();
            cla(ax);
            
            % Instructions
            title(ax, 'Preparing to capture images... Look straight at camera', 'Color', 'white');
            pause(1);
            
            % Capture 10 images from different angles
            totalImages = 10;
            capturedImages = cell(1, totalImages);
            imageCount = 1;
            
            % Capture instructions for different angles
            angles = {'straight', 'slightly left', 'slightly right', 'up', 'down'};
            imagesPerAngle = 2;
            
            for angleIdx = 1:length(angles)
                angle = angles{angleIdx};
                
                % Display angle instruction
                title(ax, sprintf('Capturing %s view images...', angle), 'Color', 'white');
                pause(0.5); % Brief pause for user to read
                
                % Capture images for this angle
                for i = 1:imagesPerAngle
                    % Capture frame
                    frame = snapshot(app.cam);
                    capturedImages{imageCount} = frame;
                    imageCount = imageCount + 1;
                    
                    % Show preview
                    imshow(frame, 'Parent', ax);
                    title(ax, sprintf('Captured %d/%d (%s)', (angleIdx-1)*imagesPerAngle+i, totalImages, angle), 'Color', 'green');
                    pause(0.1); % Minimal pause for natural variation
                end
            end
            
            title(ax, 'Processing images...', 'Color', 'white');
            
            % --- PROCESS IMAGES AND SAVE FEATURES ---
            % Create student folder
            if ~exist(studentFolder, 'dir')
                mkdir(studentFolder);
            end
            
            % Process each captured image
            featuresExtracted = 0;
            for i = 1:totalImages
                frame = capturedImages{i};
                grayFrame = rgb2gray(frame);
                
                % Detect face
                bboxes = step(app.detector, grayFrame);
                
                if app.debugMode
                    app.logMessage(sprintf('Image %d: Detected %d faces', i, size(bboxes, 1)));
                end
                
                if ~isempty(bboxes)
                    % Use the largest detected face
                    [~, idx] = max(bboxes(:,3).*bboxes(:,4));
                    bbox = bboxes(idx, :);
                    
                    % Extract face from color image
                    faceImg = imcrop(frame, bbox);
                    
                    try
                        % Extract FaceNet embedding
                        embedding = app.extractFaceNetEmbedding(faceImg);
                        
                        % Save embedding
                        featureFile = fullfile(studentFolder, sprintf('faceNetEmbedding_%d.mat', i));
                        save(featureFile, 'embedding');
                        
                        % Also save the face image for reference
                        faceFile = fullfile(studentFolder, sprintf('face_%d.jpg', i));
                        imwrite(imresize(faceImg, [160, 160]), faceFile);
                        
                        featuresExtracted = featuresExtracted + 1;
                    catch ME
                        if app.debugMode
                            app.logMessage(sprintf('Error extracting FaceNet features: %s', ME.message));
                        end
                    end
                end
            end
            
            if featuresExtracted == 0
                rmdir(studentFolder, 's'); % Remove folder if no features extracted
                uialert(app.UIFigure, 'No faces detected in any of the captured images. Student not added.', 'Face Detection Failed', 'Icon', 'error');
                return;
            end
            
            % --- UPDATE STUDENTS DATABASE ---
            newEntry = {studentName, regNumber};
            
            if isfile(studentsFile)
                try
                    % Read existing data
                    existingData = readcell(studentsFile);
                    
                    % Ensure proper format
                    if isempty(existingData) || size(existingData, 2) < 2
                        existingData = {'Name', 'RegistrationNumber'};
                    end
                    
                    % Append new entry
                    writecell([existingData; newEntry], studentsFile);
                catch
                    % File might be corrupt, recreate it
                    headers = {'Name', 'RegistrationNumber'};
                    writecell([headers; newEntry], studentsFile);
                end
            else
                % Create new file with headers
                headers = {'Name', 'RegistrationNumber'};
                writecell([headers; newEntry], studentsFile);
            end
            
            % --- CLEANUP AND CONFIRMATION ---
            app.releaseCamera();
            app.AttendancePanel.Visible = 'off';
            cla(ax);
            
            uialert(app.UIFigure, ...
                sprintf('Student "%s" added successfully with %d training images.', studentName, featuresExtracted), ...
                'Success', 'Icon', 'success');
        end

        % Value changed function: ShowPasswordCheckBox
        function ShowPasswordCheckBoxValueChanged(app, event)
      
           if app.ShowPasswordCheckBox.Value
                app.Password.Value = app.realPassword;
            else
                app.Password.Value = repmat('*', 1, length(app.realPassword));
            end
            
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
 
       % Release camera resources
            app.releaseCamera();
            
            % Reset application state
            app.LoggedInUser = [];
            app.SelectedSlot = [];
            app.MarkedStudents = {};
            app.isAttendanceRunning = false;
            
            % Reset UI components
            app.Username.Value = '';
            app.Password.Value = '';
            app.realPassword = '';
            app.SelectSlot.Items = {}; % Clear dropdown items
            app.SelectSlot.Visible = 'off';
            app.SlotDropdownDropDownLabel.Visible = 'off';
            
            % Reset button text and functionality
            app.SelectSlotButton.Text = 'Select Slot';
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSlotButtonPushed);
            
            % Show/hide panels
            app.LoginPanel.Visible = 'on';
            app.LoginPanel.Enable = 'on';
            app.MainPanel.Visible = 'off';
            app.AttendancePanel.Visible = 'off';
            
            % Clear camera axes
            cla(app.CameraAxes);
            
            uialert(app.UIFigure, 'Successfully logged out.', 'Logout', 'Icon', 'success');

        end

        % Value changing function: Password
        function PasswordValueChanging(app, event)
        % Real-time password masking
            newValue = event.Value;
            currentReal = app.realPassword;
            
            if length(newValue) > length(currentReal)
                % Character added
                addedChar = newValue(end);
                app.realPassword = [currentReal addedChar];
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            elseif length(newValue) < length(currentReal)
                % Character deleted
                app.realPassword = currentReal(1:end-1);
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            else
                % Text was pasted or replaced - handle this case
                if ~app.ShowPasswordCheckBox.Value
                    app.Password.Value = repmat('*', 1, length(app.realPassword));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginPanel = uipanel(app.UIFigure);
            app.LoginPanel.Title = 'LoginPanel';
            app.LoginLabel = uilabel(app.LoginPanel);
            app.LoginLabel.Text = 'Login';
            app.PasswordLabel = uilabel(app.LoginPanel);
            app.PasswordLabel.Text = 'Password ';
            app.Username = uieditfield(app.LoginPanel, 'text');
            app.Password = uieditfield(app.LoginPanel, 'text');
            app.Password.ValueChangingFcn = createCallbackFcn(app, @PasswordValueChanging, true);
            app.LoginButton = uibutton(app.LoginPanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.NewuserSignupButton = uibutton(app.LoginPanel, 'push');
            app.NewuserSignupButton.ButtonPushedFcn = createCallbackFcn(app, @NewuserSignupButtonPushed, true);
            app.NewuserSignupButton.Text = 'New user ? Signup';
            app.ShowPasswordCheckBox = uicheckbox(app.LoginPanel);
            app.ShowPasswordCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPasswordCheckBoxValueChanged, true);
            app.ShowPasswordCheckBox.Text = 'ShowPassword';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'MainPanel';
            app.MainPanel.ButtonDownFcn = createCallbackFcn(app, @MainPanelButtonDown, true);
            app.TakeAttendanceButton = uibutton(app.MainPanel, 'push');
            app.TakeAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @TakeAttendanceButtonPushed, true);
            app.TakeAttendanceButton.Text = 'Take Attendance';
            app.AttendancePanel = uipanel(app.MainPanel);
            app.AttendancePanel.Title = 'Attendance';
            app.CameraAxes = uiaxes(app.AttendancePanel);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.StopAttendanceButton = uibutton(app.MainPanel, 'push');
            app.StopAttendanceButton.ButtonPushedFcn = createCallbackFcn(app, @StopAttendanceButtonPushed, true);
            app.StopAttendanceButton.Text = 'Stop Attendance';
            app.AddStudentButton = uibutton(app.MainPanel, 'push');
            app.AddStudentButton.ButtonPushedFcn = createCallbackFcn(app, @AddStudentButtonPushed, true);
            app.AddStudentButton.Text = 'Add Student';
            app.SlotDropdownDropDownLabel = uilabel(app.MainPanel);
            app.SlotDropdownDropDownLabel.Text = 'SlotDropdown';
            app.SelectSlot = uidropdown(app.MainPanel);
            app.SelectSlotButton = uibutton(app.MainPanel, 'push');
            app.SelectSlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSlotButtonPushed, true);
            app.SelectSlotButton.Text = 'Select Slot';
            app.CreateSlotButton = uibutton(app.MainPanel, 'push');
            app.CreateSlotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSlotButtonPushed, true);
            app.CreateSlotButton.Text = 'CreateSlotButton';
            app.LogoutButton = uibutton(app.MainPanel, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttendanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end